classdef DIP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        Panel_5           matlab.ui.container.Panel
        GridLayout6       matlab.ui.container.GridLayout
        Button26          matlab.ui.control.Button
        Button25          matlab.ui.control.Button
        Button24          matlab.ui.control.Button
        Button23          matlab.ui.control.Button
        Button22          matlab.ui.control.Button
        Button21          matlab.ui.control.Button
        Panel_4           matlab.ui.container.Panel
        GridLayout5       matlab.ui.container.GridLayout
        detectPasteBtn    matlab.ui.control.Button
        deleteObjBtn      matlab.ui.control.Button
        smoothBtn         matlab.ui.control.Button
        imeroadBtn        matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        GridLayout4       matlab.ui.container.GridLayout
        edgeDetectionBtn  matlab.ui.control.Button
        grayScaleBtn      matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        GridLayout3       matlab.ui.container.GridLayout
        selectImgBtn      matlab.ui.control.Button
        axImg             matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        ax26              matlab.ui.control.UIAxes
        ax25              matlab.ui.control.UIAxes
        ax24              matlab.ui.control.UIAxes
        ax23              matlab.ui.control.UIAxes
        ax22              matlab.ui.control.UIAxes
        ax21              matlab.ui.control.UIAxes
        ax16              matlab.ui.control.UIAxes
        ax15              matlab.ui.control.UIAxes
        ax14              matlab.ui.control.UIAxes
        ax13              matlab.ui.control.UIAxes
        ax12              matlab.ui.control.UIAxes
        ax11              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% 全局属性
        % 考虑添加一个控制属性step 标识程序正在进行哪一步 便于警告信息的弹出
        % 添加reset函数 重置所有属性
        imagePath           % 图片地址 
        imageData           % 图片数据  
        imageGray           % 图片灰度化处理 
        imageEdge           % 图片边缘检测
        imageErode          % 图片腐蚀
        imageSmooth         % Description
        imageDeleteObj      % Description
        imageDetect         % Description

        licenseGray         % Description
        licenseHist         % Description
        licenseBin          % Description
        licenseDetect       % Description
        licenseMid          % Description
        license                     
    end
    
    methods (Access = private)
        
        function res = my_split(app)
            tmp = app.licenseMid;
            [m, n] = size(tmp);


            top = 1; bottom = m; left = 1; right = n;
            for i = 1:m
                RS(i) = sum(tmp(i, :));
            end
            for i = 1:n
                CS(i) = sum(tmp(:, i));
            end


            while(RS(top) == 0 && top <= m)
                top = top + 1;
            end
            if top - 10 > 1
                top = top - 10;
            else 
                top = 1;
            end

            while(RS(bottom) == 0 && bottom >= 1)
                bottom = bottom - 1;
            end
            if bottom + 10 < m
                bottom = bottom + 10;
            else 
                bottom = m;
            end

            while(CS(left) == 0 && left <= n)
                left = left + 1;
            end
            if left - 10 >= 1
                left = left - 10;
            else 
                left = 1;
            end

            while(CS(right) == 0 && right >= 1)
                right = right - 1;
            end
            if right + 10 <= n
                right = right + 10;
            else 
                right = n;
            end

            w = right - left;
            h = bottom - top;
            res = imcrop(tmp, [left top w h]);
        end
        
        function res = get_word(app)
            tmp = app.license;
            [~, n] = size(tmp);
            left = 1; width = 1;
            for i = 1:n
                CS(i) = sum(tmp(:, i));
            end

            while(CS(left) == 0 && left < n)
                left = left + 1;
            end

            if left == n
                res = [];
                return
            end

            while(CS(left+width) ~= 0 && left+width < n)
                width = width + 1;
            end
            
            res = tmp(:, left:left+width-1);
            tmp(:, left:left+width-1) = 0;

            app.license = tmp;
            % figure;
            % subplot(121); imshow(res);
            % subplot(122); imshow(app.license);
        end
        
        function res = delete_obj(app, input, thresholdSize)
            if ~islogical(input)
                input = input ~= 0
            end

            [labelInput, num] = bwlabel(input);
            regionSS = regionprops(labelInput, 'Area', 'PixelIdxList');
            res = false(size(input));

            for i = 1:num
                if regionSS(i).Area >= thresholdSize
                    res(regionSS(i).PixelIdxList) = true;
                end
            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectImgBtn
        function uploadImg(app, event)
            %% 图片选择
            axis(app.axImg,'off');             %隐藏坐标轴
            % 当前目录下打开文件管理器
            [fileName, pathName] = uigetfile({'*.jpg;*.png;*.bmp;*.tif'}, '请选择图片路径', fileparts(mfilename('fullpath')));
            if pathName == 0
                return;
            end

            app.imagePath = [pathName fileName];
            app.imageData = imread(app.imagePath);

            assignin('base', "imagePath", app.imagePath);
            assignin('base', 'imageData', app.imageData);  
            imshow(app.imageData, 'Parent', app.axImg);
        end

        % Button pushed function: grayScaleBtn
        function grayScale(app, event)
            %% 灰度处理
            % 灰度权重比是否更优？灰度权重化是否有必要？是否可以直接调用灰度函数
            if isempty(app.imageData)
                uialert(app.UIFigure, '请先上传图片！', '警告', 'Icon', 'warning')
                return
            end
            axis(app.ax11,'off');             %隐藏坐标轴

            imgR = app.imageData(:, :, 1);
            imgG = app.imageData(:, :, 2);
            imgB = app.imageData(:, :, 3);

            wr = 0.30; wg = 0.59; wb = 0.11;
            g = (wr * imgR + wg * imgG + wb * imgB);

            app.imageGray = medfilt2(g);
            assignin('base', 'imageGray', app.imageGray);  
            imshow(app.imageGray, "Parent", app.ax11);

        end

        % Button pushed function: edgeDetectionBtn
        function edgeDetection(app, event)
            %% 边缘检测
            if isempty(app.imageGray)
                uialert(app.UIFigure, '请先进行灰度处理！', '警告', 'Icon', 'warning')
                return;
            end
            axis(app.ax12,'off');             %隐藏坐标轴

            app.imageEdge = edge(app.imageGray, 'roberts', 0.07);
            assignin('base', 'imageEdge', app.imageEdge);  
            imshow(app.imageEdge, "Parent", app.ax12);
            
        end

        % Button pushed function: imeroadBtn
        function imeroadBtnButtonPushed(app, event)
            %% 腐蚀
            if isempty(app.imageEdge)
                uialert(app.UIFigure, '请先进行边缘检测！', '警告', 'Icon', 'warning')
                return;
            end
            axis(app.ax13,'off');             %隐藏坐标轴

            se = strel('rectangle', [16, 1]);
            app.imageErode = imerode(app.imageEdge, se);
            assignin('base', 'imageErode', app.imageErode);  
            imshow(app.imageErode, 'Parent', app.ax13);
        end

        % Button pushed function: smoothBtn
        function smoothBtnButtonPushed(app, event)
            %% 平滑处理
            if isempty(app.imageEdge)
                uialert(app.UIFigure, '请先进行图像腐蚀！', '警告', 'Icon', 'warning')
                return;
            end
            axis(app.ax14,'off');             %隐藏坐标轴

            se = strel('rectangle', [300, 300]);
            app.imageSmooth = imclose(app.imageErode, se);
            assignin('base', 'imageSmooth', app.imageSmooth);
            imshow(app.imageSmooth, 'Parent', app.ax14);
        end

        % Button pushed function: deleteObjBtn
        function deleteObjBtnButtonPushed(app, event)
            %% 移除对象
            % 自动参数 只保留最大的区域
            % 如何删除可能存在的干扰 或者如何优化之前的步骤？
            % 首先考虑是腐蚀的问题 是否可以通过调节腐蚀的参数完全解决该问题？
            % 其次考虑移除的优化
            
            if isempty(app.imageSmooth)
                uialert(app.UIFigure, '请先进行平滑处理！', '警告', 'Icon', 'warning')
                return;
            end
            axis(app.ax15, 'off');
            
            cc = bwconncomp(app.imageSmooth);
            stats = regionprops(cc, 'Area');
            areas = [stats.Area];

            if isempty(areas)
                uialert(app.UIFigure, '没有检测到联通区域！', '警告', 'Icon','warning');
                return;
            end

            sortedAreas = sort(areas, 'descend');
            tar = sortedAreas(1);

            app.imageDeleteObj = bwareaopen(app.imageSmooth, tar);
            assignin('base', 'imageDeleteObj', app.imageDeleteObj);
            imshow(app.imageDeleteObj, 'Parent', app.ax15);

            
        end

        % Button pushed function: detectPasteBtn
        function detectPasteBtnButtonPushed(app, event)
            %% 定位剪贴
            if isempty(app.imageDeleteObj)
                uialert(app.UIFigure, '请先进行移除对象！', '警告', 'Icon', 'warning')
                return;
            end

            pic = app.imageDeleteObj;
            [r, c] = size(pic);
            pr1 = 0;
            pr2 = 0;
            for i = 1 : r
                RR(i) = sum(pic(i, :));
            end
            judgeR = 30;
            k = 0;
            for i = 1 : r
                if (pr1 == 0 && RR(i) ~= 0)
                    pr1 = i;
                end
                if (pr1 ~= 0 && pr2 == 0 && RR(i) == 0)
                    pr2 = i;
                    k = pr2 - pr1;
                end
                if (k ~= 0 && k < judgeR)
                    pr1 = 0;
                    pr2 = 0;
                    k = 0;
                end
                if (k > judgeR)
                    break
                end
            end
            assignin('base', 'row', [pr1, pr2]);
            assignin('base', 'RR', RR);

            pc1 = 0;
            pc2 = 0;
            for i = 1 : c
                CC(i) = sum(pic(:, i));
            end
            judgeC = 120;
            k = 0;
            for i = 1 : c
                if (pc1 == 0 && CC(i) ~= 0)
                    pc1 = i;
                end
                if (pc1 ~= 0 && pc2 == 0 && CC(i) == 0)
                    pc2 = i;
                    k = pc2 - pc1;
                end
                if (k ~= 0 && k < judgeC)
                    pc1 = 0;
                    pc2 = 0;
                    k = 0;
                end
                if (k > judgeC)
                    break
                end
            end
            assignin('base', 'col', [pc1, pc2]);
            assignin('base', 'CC', CC);

            I = imread(app.imagePath);
            tar = zeros([pr2 - pr1, pc2 - pc1, 3], 'uint8');
            for i = pr1 : pr2
                for j = pc1 : pc2
                    tar(i-pr1+1, j-pc1+1, 1) = I(i, j, 1);
                    tar(i-pr1+1, j-pc1+1, 2) = I(i, j, 2);
                    tar(i-pr1+1, j-pc1+1, 3) = I(i, j, 3);
                end
            end

            app.imageDetect = tar;
            axis(app.ax16, 'off');
            imshow(app.imageDetect, 'Parent', app.ax16);
        end

        % Button pushed function: Button21
        function Button21Pushed(app, event)
            %% 车牌识别 灰度处理
            app.licenseGray = rgb2gray(app.imageDetect);
            axis(app.ax21, 'off');
            imshow(app.licenseGray, 'Parent', app.ax21);
        end

        % Button pushed function: Button22
        function Button22Pushed(app, event)
            %% 直方图均衡化
            app.licenseHist = histeq(app.licenseGray, 64);
            axis(app.ax22, 'off');
            imshow(app.licenseHist, 'Parent', app.ax22);
        end

        % Callback function: Button23, ax23
        function ax23ButtonDown(app, event)
            %% 二值化
            app.licenseBin = imbinarize(app.licenseGray);
            axis(app.ax23, 'off');
            imshow(app.licenseBin, 'Parent', app.ax23);
        end

        % Button pushed function: Button24
        function Button24Pushed(app, event)
            %% 移除对象
            % 直接移除对象的效果并不好 可能会移除一些偏旁部首 比如浙的上面两个 虽然消除之后应该依旧可以识别
            % - 考虑形态学方法 使用开运算消除小圆点
            % app.licenseDetect = app.delete_obj(app.licenseBin, 50);
            app.licenseDetect = bwareaopen(app.licenseBin, 50);
            axis(app.ax24, 'off');
            imshow(app.licenseDetect, 'Parent', app.ax24);
        end

        % Button pushed function: Button25
        function Button25Pushed(app, event)
            %% 中值滤波
            % 手动实现一下中值滤波
            app.licenseMid = medfilt2(app.licenseDetect, [3 3]);
            axis(app.ax25, 'off');
            imshow(app.licenseMid, 'Parent', app.ax25);

        end

        % Button pushed function: Button26
        function Button26Pushed(app, event)
            %% 字符切割 & 模板匹配
            % 字符切割的时候需要考虑边缘两侧的干扰条 
            % - 可以考虑借鉴判断宽度的方式？具体看是否可能存在细长的切割块
            % 考虑输出切割结果
            % - 多留空位 会出现警车这类多出来的字符
            % - 特殊字符 川 I 
            % - 额外删除 黑 冀 津 晋 ... 感觉不用考虑 本来就有问题
            % - 车牌位数 燃油车是7位 新能源是8位
            app.license = app.my_split();
            axis(app.ax26, 'off');
            imshow(app.license, 'Parent', app.ax26);

            words = {};
            others = {};
            while true
                word = app.get_word();
                if isempty(word)
                    break;
                end
                [m, n] = size(word);
                if m / n > 3
                    % 对于宽高比不对的图片舍弃
                    continue
                end
                words{end+1} = word;
                others{end+1} = app.license;
            end

            lth = length(words);
            assignin("base", "lth", lth);
            figure();
            for i = 1:lth
                subplot(2, lth, i);
                imshow(words{i});
                title("切割字符");
                subplot(2, lth, i+lth);
                imshow(others{i});
                title("剩余字符");
            end
            
            file_name = char(['0':'9' 'A':'Z' '川赣贵黑吉冀津晋京警辽鲁蒙闽宁陕苏皖湘豫粤浙']);
            assignin('base', 'pwd', pwd);
            tmp = strcat('.\patterns\', file_name(1), '.jpg');
            I = imread(tmp);
            [em, en] = size(I);
            assignin('base', "resize", [em, en]);

            ans_cell = {};
            for i = 1:lth
                word = words{i};
                e_word = imresize(word, [em, en], "nearest");

                if i == 1
                    mmin = 37; mmax = 58;
                elseif i == 2
                    mmin = 11; mmax = 36;
                elseif i >= 3
                    mmin = 1; mmax = 36;
                end

                cnt = [];
                for idx = mmin:mmax
                    f_name = strcat('.\patterns\', file_name(idx), '.jpg');
                    pattern = imread(f_name);
                    pattern = imbinarize(pattern, 0.5);

                    diff = 0;
                    for j=1:em
                        for k=1:en
                            if e_word(j, k) ~= pattern(j, k)
                                diff = diff + 1;
                            end
                        end
                    end
                    cnt(end+1) = diff;
                end

                diff_min = min(cnt);
                idx = find(cnt == diff_min);

                ans_cell{end+1} = file_name(idx + mmin - 1);
            end
            assignin("base", "ans_cell", ans_cell);


            I = imread(app.imagePath);
            R = I(:, :, 1);
            G = I(:, :, 2);
            B = I(:, :, 3);

            r_mean = mean(R(:));
            g_mean = mean(G(:));
            b_mean = mean(B(:));

            if b_mean > g_mean && b_mean > r_mean
                color = '蓝色';
            elseif g_mean > b_mean && g_mean > r_mean
                color = '绿色';
            elseif r_mean > 170 && g_mean > 170 && b_mean > 170
                color = '白色';
            else
                color = '黄色';
            end
             
            output = sprintf('这是一张 %s 车牌，车牌号是 %s', color, strjoin(string(ans_cell), ''));
            msgbox(output, '检测结果', 'help');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '结果';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ax11 = uiaxes(app.GridLayout2);
            title(app.ax11, '灰度处理')
            zlabel(app.ax11, ' ')
            app.ax11.Visible = 'off';
            app.ax12 = uiaxes(app.GridLayout2);
            title(app.ax12, '边缘检测')
            xlabel(app.ax12, ' ')
            ylabel(app.ax12, ' ')
            zlabel(app.ax12, ' ')
            app.ax12.Visible = 'off';
            app.ax13 = uiaxes(app.GridLayout2);
            title(app.ax13, '图像腐蚀')
            xlabel(app.ax13, ' ')
            ylabel(app.ax13, ' ')
            zlabel(app.ax13, ' ')
            app.ax13.Visible = 'off';
            app.ax14 = uiaxes(app.GridLayout2);
            title(app.ax14, '平滑处理')
            xlabel(app.ax14, ' ')
            ylabel(app.ax14, ' ')
            zlabel(app.ax14, ' ')
            app.ax14.Visible = 'off';
            app.ax15 = uiaxes(app.GridLayout2);
            title(app.ax15, '移除对象')
            xlabel(app.ax15, ' ')
            ylabel(app.ax15, ' ')
            zlabel(app.ax15, ' ')
            app.ax15.Visible = 'off';
            app.ax16 = uiaxes(app.GridLayout2);
            title(app.ax16, '定位剪贴')
            xlabel(app.ax16, ' ')
            ylabel(app.ax16, ' ')
            zlabel(app.ax16, ' ')
            app.ax16.Visible = 'off';
            app.ax21 = uiaxes(app.GridLayout2);
            title(app.ax21, '灰度处理')
            xlabel(app.ax21, ' ')
            ylabel(app.ax21, ' ')
            zlabel(app.ax21, ' ')
            app.ax21.Visible = 'off';
            app.ax22 = uiaxes(app.GridLayout2);
            title(app.ax22, '直方图均衡化')
            xlabel(app.ax22, ' ')
            ylabel(app.ax22, ' ')
            zlabel(app.ax22, ' ')
            app.ax22.Visible = 'off';
            app.ax23 = uiaxes(app.GridLayout2);
            title(app.ax23, '二值化')
            xlabel(app.ax23, ' ')
            ylabel(app.ax23, ' ')
            zlabel(app.ax23, ' ')
            app.ax23.ButtonDownFcn = createCallbackFcn(app, @ax23ButtonDown, true);
            app.ax23.Visible = 'off';
            app.ax24 = uiaxes(app.GridLayout2);
            title(app.ax24, '移除对象')
            xlabel(app.ax24, ' ')
            ylabel(app.ax24, ' ')
            zlabel(app.ax24, ' ')
            app.ax24.Visible = 'off';
            app.ax25 = uiaxes(app.GridLayout2);
            title(app.ax25, '中值滤波')
            xlabel(app.ax25, ' ')
            ylabel(app.ax25, ' ')
            zlabel(app.ax25, ' ')
            app.ax25.Visible = 'off';
            app.ax26 = uiaxes(app.GridLayout2);
            title(app.ax26, '输出结果')
            xlabel(app.ax26, ' ')
            ylabel(app.ax26, ' ')
            zlabel(app.ax26, ' ')
            app.ax26.Visible = 'off';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '选择图片';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.axImg = uiaxes(app.GridLayout3);
            title(app.axImg, '原始图片')
            xlabel(app.axImg, ' ')
            ylabel(app.axImg, ' ')
            zlabel(app.axImg, ' ')
            app.axImg.Visible = 'off';
            app.selectImgBtn = uibutton(app.GridLayout3, 'push');
            app.selectImgBtn.ButtonPushedFcn = createCallbackFcn(app, @uploadImg, true);
            app.selectImgBtn.Text = '上传图片';
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '图像预处理';
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.grayScaleBtn = uibutton(app.GridLayout4, 'push');
            app.grayScaleBtn.ButtonPushedFcn = createCallbackFcn(app, @grayScale, true);
            app.grayScaleBtn.Text = '灰度处理';
            app.edgeDetectionBtn = uibutton(app.GridLayout4, 'push');
            app.edgeDetectionBtn.ButtonPushedFcn = createCallbackFcn(app, @edgeDetection, true);
            app.edgeDetectionBtn.Text = '边缘检测';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '车牌定位';
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.imeroadBtn = uibutton(app.GridLayout5, 'push');
            app.imeroadBtn.ButtonPushedFcn = createCallbackFcn(app, @imeroadBtnButtonPushed, true);
            app.imeroadBtn.Text = '图像腐蚀';
            app.smoothBtn = uibutton(app.GridLayout5, 'push');
            app.smoothBtn.ButtonPushedFcn = createCallbackFcn(app, @smoothBtnButtonPushed, true);
            app.smoothBtn.Text = '平滑处理';
            app.deleteObjBtn = uibutton(app.GridLayout5, 'push');
            app.deleteObjBtn.ButtonPushedFcn = createCallbackFcn(app, @deleteObjBtnButtonPushed, true);
            app.deleteObjBtn.Text = '移除对象';
            app.detectPasteBtn = uibutton(app.GridLayout5, 'push');
            app.detectPasteBtn.ButtonPushedFcn = createCallbackFcn(app, @detectPasteBtnButtonPushed, true);
            app.detectPasteBtn.Text = '定位剪贴';
            app.Panel_5 = uipanel(app.GridLayout);
            app.Panel_5.Title = '车牌识别';
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Button21 = uibutton(app.GridLayout6, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @Button21Pushed, true);
            app.Button21.Text = '灰度处理';
            app.Button22 = uibutton(app.GridLayout6, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @Button22Pushed, true);
            app.Button22.Text = '直方图均衡化';
            app.Button23 = uibutton(app.GridLayout6, 'push');
            app.Button23.ButtonPushedFcn = createCallbackFcn(app, @ax23ButtonDown, true);
            app.Button23.Text = '二值化';
            app.Button24 = uibutton(app.GridLayout6, 'push');
            app.Button24.ButtonPushedFcn = createCallbackFcn(app, @Button24Pushed, true);
            app.Button24.Text = '移除对象';
            app.Button25 = uibutton(app.GridLayout6, 'push');
            app.Button25.ButtonPushedFcn = createCallbackFcn(app, @Button25Pushed, true);
            app.Button25.Text = '中值滤波';
            app.Button26 = uibutton(app.GridLayout6, 'push');
            app.Button26.ButtonPushedFcn = createCallbackFcn(app, @Button26Pushed, true);
            app.Button26.Text = '输出结果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end